classdef SNSC_Abv_App_02092021 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileOptsMenu                 matlab.ui.container.Menu
        LoadFile                     matlab.ui.container.Menu
        LFPPanel                     matlab.ui.container.Panel
        GridLayout                   matlab.ui.container.GridLayout
        LFP1                         matlab.ui.control.UIAxes
        LFP2                         matlab.ui.control.UIAxes
        LFP3                         matlab.ui.control.UIAxes
        LFP4                         matlab.ui.control.UIAxes
        UniqueValsEditFieldLabel     matlab.ui.control.Label
        uLFP1                        matlab.ui.control.NumericEditField
        VarofsigEditFieldLabel       matlab.ui.control.Label
        vLFP1                        matlab.ui.control.NumericEditField
        NumberoffilesEditFieldLabel  matlab.ui.control.Label
        numfiles                     matlab.ui.control.NumericEditField
        MinutesofRecEditFieldLabel   matlab.ui.control.Label
        recDur                       matlab.ui.control.NumericEditField
        UniqueValsEditFieldLabel_2   matlab.ui.control.Label
        uLFP2                        matlab.ui.control.NumericEditField
        VarofsigEditFieldLabel_2     matlab.ui.control.Label
        vLFP2                        matlab.ui.control.NumericEditField
        UniqueValsEditFieldLabel_3   matlab.ui.control.Label
        uLFP3                        matlab.ui.control.NumericEditField
        UniqueValsEditFieldLabel_4   matlab.ui.control.Label
        uLFP4                        matlab.ui.control.NumericEditField
        VarofsigEditFieldLabel_3     matlab.ui.control.Label
        vLFP3                        matlab.ui.control.NumericEditField
        VarofsigEditFieldLabel_4     matlab.ui.control.Label
        vLFP4                        matlab.ui.control.NumericEditField
        PSDPanel                     matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes_3                     matlab.ui.control.UIAxes
        UIAxes_4                     matlab.ui.control.UIAxes
        CEEGIDPanel                  matlab.ui.container.Panel
        DropDown_2                   matlab.ui.control.DropDown
        DropDown_3                   matlab.ui.control.DropDown
        DropDown_4                   matlab.ui.control.DropDown
        DropDown_5                   matlab.ui.control.DropDown
        PLOTPSDButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        fileLOC % Description
        fileLIST
        eeglist
        labTab
        curCHAN
        allChan
    end
    
    methods (Access = private)
        
        function plotDATA(app)
            
            chanTYPE = {'EEG','LFP','EOG','EMG'};
            for ci = 1:length(chanTYPE)
                app.curCHAN = chanTYPE{ci};
                switch chanTYPE{ci}
                    
                    case 'LFP'
                        % Extract LFP IDs
                        chanIDS = app.labTab.ID(ismember(app.labTab.Label,app.curCHAN));
                        
                        LFPdat = zeros(length(chanIDS),length(app.allChan.(chanIDS{1})));
                        for chI = 1:length(chanIDS)
                            LFPdat(chI,:) = app.allChan.(chanIDS{chI});
                        end
                        % Compute mean
                        LFPmean = mean(LFPdat);
                        
                        % Sutract common reference
                        LFPref = LFPdat - LFPmean;
                        
                        % Loop through LFP 
                        for li = 1:length(chanIDS)
                        
                            tmpUv = numel(unique(LFPref(li,:)));
                            app.(['uLFP',num2str(li)]).Value = tmpUv;
                            
                            tmpVar = var(LFPref(li,:));
                            app.(['vLFP',num2str(li)]).Value = tmpVar;
                            
                        end
                        
                        % Plot over Guassian
                        for lp = 1:length(chanIDS)
                            
                            lfpn = normalize(LFPref(lp,:),'scale');
                            sdlfpn = std(lfpn);
                            r = normrnd(0,sdlfpn,[1,length(lfpn)]);
                            
                            histogram(app.(['LFP',num2str(lp)]),r);
                            hold(app.(['LFP',num2str(lp)]), 'on')
                            histogram(app.(['LFP',num2str(lp)]),lfpn);
                            
                            
                        end


                    case 'EEG'
                        
                    case 'EOG'
                        
                    case 'EMG'
                        
                end
            end
            
        end
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: LoadFile
        function LoadFileMenuSelected(app, event)
            app.fileLOC = uigetdir();
            
            cd(app.fileLOC)
            
            mdir = dir('*.mat');
            mdir2 = {mdir.name};
            app.fileLIST = mdir2;
            % Use Matfile function to import the appropriate files
            
            matob = matfile('F210110-0001.mat');
            varlist = who(matob);
            app.eeglist = varlist(contains(varlist,'CEEG'));

            chanNum = 1:16;
            chanLab = {'EEG','EEG','LFP','EEG','EEG','EEG','LFP','EEG',...
                'EEG','LFP','EOG','EOG','EMG','EMG','EMG','LFP'};
            chanID = {'F3','Fz','D0','A1','C3','Cz','D1','A2','O1','D2',...
                'EOG1','EOG2','Chin1','Chin2','ChinZ','D3'};
            
            app.labTab = table(chanNum,chanLab,chanID,'VariableNames',{'Number',...
                'Label','ID'});
            
            chanTYPE = {'EEG','LFP','EOG','EMG'};
            % Combine first 3 files
            app.allChan = struct;
            for fi = 1:3
                
                curFile = app.fileLIST{fi};
                
                load(curFile,app.eeglist{:});
                
                for ci = 1:length(chanTYPE)
                    app.curCHAN = chanTYPE{ci};
                    % Get channel list
                    chanNums = app.labTab.Number(ismember(app.labTab.Label,app.curCHAN));
                    chanIDS = app.labTab.ID(ismember(app.labTab.Label,app.curCHAN));
                    
                    eeglNum1 = cellfun(@(x) strsplit(x,'_'), app.eeglist,'UniformOutput',false);
                    eeglNum2 = cellfun(@(x) str2double(x{3}), eeglNum1, 'UniformOutput', true);
                    
                    for ni = 1:length(chanNums)
                        
                        tmpChan = ismember(eeglNum2,chanNums(ni));
                        tmpRows = app.eeglist(tmpChan);
                        rawRow = tmpRows{1};
                        
                        dat1 = eval(rawRow);
                        
                        if ~isfield(app.allChan,chanIDS{ni})
                            app.allChan.(chanIDS{ni}) = dat1;
                        else
                            app.allChan.(chanIDS{ni}) = [app.allChan.(chanIDS{ni}) , dat1];
                        end
                        
                    end
                    
                    
                end
                
            end
            % Extract recording information
            app.numfiles.Value = numel(app.fileLIST);
            
            totTime = 0;
%             allTime = zeros(1,length(app.fileLIST));
            for ff = 1:length(app.fileLIST)
                
                curF = app.fileLIST{ff};
                load(curF,'CEEG_2___02___Fz_TimeBegin','CEEG_2___02___Fz_TimeEnd');
                
                tmpTime = CEEG_2___02___Fz_TimeEnd - CEEG_2___02___Fz_TimeBegin;
                
%                 allTime(ff) = tmpTime;
                
                totTime = totTime + tmpTime;

            end
            app.recDur.Value = (round(totTime)/60);
            
            % Update plots
            plotDATA(app)
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileOptsMenu = uimenu(app.UIFigure);
            app.FileOptsMenu.Text = 'FileOpts';
            app.LoadFile = uimenu(app.FileOptsMenu);
            app.LoadFile.MenuSelectedFcn = createCallbackFcn(app, @LoadFileMenuSelected, true);
            app.LoadFile.Text = 'Load File';
            app.LFPPanel = uipanel(app.UIFigure);
            app.LFPPanel.Title = 'LFP';
            app.GridLayout = uigridlayout(app.LFPPanel);
            app.LFP1 = uiaxes(app.GridLayout);
            zlabel(app.LFP1, 'Z')
            app.LFP1.XTick = [];
            app.LFP1.YTick = [];
            app.LFP2 = uiaxes(app.GridLayout);
            zlabel(app.LFP2, 'Z')
            app.LFP2.XTick = [];
            app.LFP2.YTick = [];
            app.LFP3 = uiaxes(app.GridLayout);
            zlabel(app.LFP3, 'Z')
            app.LFP3.XTick = [];
            app.LFP3.YTick = [];
            app.LFP4 = uiaxes(app.GridLayout);
            zlabel(app.LFP4, 'Z')
            app.LFP4.XTick = [];
            app.LFP4.YTick = [];
            app.UniqueValsEditFieldLabel = uilabel(app.UIFigure);
            app.UniqueValsEditFieldLabel.Text = '# Unique Vals';
            app.uLFP1 = uieditfield(app.UIFigure, 'numeric');
            app.VarofsigEditFieldLabel = uilabel(app.UIFigure);
            app.VarofsigEditFieldLabel.Text = 'Var. of sig';
            app.vLFP1 = uieditfield(app.UIFigure, 'numeric');
            app.NumberoffilesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberoffilesEditFieldLabel.Text = 'Number of files = ';
            app.numfiles = uieditfield(app.UIFigure, 'numeric');
            app.numfiles.Editable = 'off';
            app.MinutesofRecEditFieldLabel = uilabel(app.UIFigure);
            app.MinutesofRecEditFieldLabel.Text = 'Minutes of Rec. = ';
            app.recDur = uieditfield(app.UIFigure, 'numeric');
            app.recDur.Editable = 'off';
            app.UniqueValsEditFieldLabel_2 = uilabel(app.UIFigure);
            app.UniqueValsEditFieldLabel_2.Text = '# Unique Vals';
            app.uLFP2 = uieditfield(app.UIFigure, 'numeric');
            app.VarofsigEditFieldLabel_2 = uilabel(app.UIFigure);
            app.VarofsigEditFieldLabel_2.Text = 'Var. of sig';
            app.vLFP2 = uieditfield(app.UIFigure, 'numeric');
            app.UniqueValsEditFieldLabel_3 = uilabel(app.UIFigure);
            app.UniqueValsEditFieldLabel_3.Text = '# Unique Vals';
            app.uLFP3 = uieditfield(app.UIFigure, 'numeric');
            app.UniqueValsEditFieldLabel_4 = uilabel(app.UIFigure);
            app.UniqueValsEditFieldLabel_4.Text = '# Unique Vals';
            app.uLFP4 = uieditfield(app.UIFigure, 'numeric');
            app.VarofsigEditFieldLabel_3 = uilabel(app.UIFigure);
            app.VarofsigEditFieldLabel_3.Text = 'Var. of sig';
            app.vLFP3 = uieditfield(app.UIFigure, 'numeric');
            app.VarofsigEditFieldLabel_4 = uilabel(app.UIFigure);
            app.VarofsigEditFieldLabel_4.Text = 'Var. of sig';
            app.vLFP4 = uieditfield(app.UIFigure, 'numeric');
            app.PSDPanel = uipanel(app.UIFigure);
            app.PSDPanel.Title = 'PSD';
            app.GridLayout2 = uigridlayout(app.PSDPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.GridLayout2);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.GridLayout2);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_4 = uiaxes(app.GridLayout2);
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.CEEGIDPanel = uipanel(app.UIFigure);
            app.CEEGIDPanel.Title = 'CEEG ID';
            app.DropDown_2 = uidropdown(app.CEEGIDPanel);
            app.DropDown_3 = uidropdown(app.CEEGIDPanel);
            app.DropDown_4 = uidropdown(app.CEEGIDPanel);
            app.DropDown_5 = uidropdown(app.CEEGIDPanel);
            app.PLOTPSDButton = uibutton(app.UIFigure, 'push');
            app.PLOTPSDButton.Text = 'PLOT PSD';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SNSC_Abv_App_02092021

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end