classdef SNSC_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        FileOptionsMenu  matlab.ui.container.Menu
        LoadDate         matlab.ui.container.Menu
        ChannelTypeMenu  matlab.ui.container.Menu
        EEGMenu          matlab.ui.container.Menu
        LFPMenu          matlab.ui.container.Menu
        EOGMenu          matlab.ui.container.Menu
        EMGMenu          matlab.ui.container.Menu
        GridLayout       matlab.ui.container.GridLayout
        UIAxes_1         matlab.ui.control.UIAxes
        UIAxes_2         matlab.ui.control.UIAxes
        UIAxes_3         matlab.ui.control.UIAxes
        UIAxes_4         matlab.ui.control.UIAxes
        UIAxes_5         matlab.ui.control.UIAxes
        UIAxes_6         matlab.ui.control.UIAxes
        UIAxes_7         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        curFILEnum % Description
        fileLOC
        fileLIST
        eeglist
        labTab
        curCHAN
        GraphNum
    end
    
    methods (Access = private)
        
        function updatePLOTS(app)
            
            % Make graphs visible
            for gi = 1:7
                
                app.(['UIAxes_',num2str(gi)]).Visible = 'off';
                cla(app.(['UIAxes_',num2str(gi)]))
                
            end
            
            % Make graphs visible
            for gi = 1:app.GraphNum
                
                app.(['UIAxes_',num2str(gi)]).Visible = 'on';
                
            end
            
            % Load Channels for current file
            curFile = app.fileLIST{app.curFILEnum};
            
            % Got to location
            cd(app.fileLOC)
            
            load(curFile,app.eeglist{:});
            
            % Get channel list
            chanNums = app.labTab.Number(ismember(app.labTab.Label,app.curCHAN));
            
            eeglNum1 = cellfun(@(x) strsplit(x,'_'), app.eeglist,'UniformOutput',false);
            eeglNum2 = cellfun(@(x) str2double(x{3}), eeglNum1, 'UniformOutput', true);
            
            % Process the data
            rawDAT = cell(length(chanNums),1);
            for ci = 1:length(chanNums)
                
                tmpChan = ismember(eeglNum2,chanNums(ci));
                tmpRows = app.eeglist(tmpChan);
                rawRow = tmpRows{1};
                
                dat1 = eval(rawRow);
                rawDAT{ci} = dat1;
                
            end
            
            procData = procdata(app , rawDAT);

            
            for ci = 1:length(chanNums)
                
                pdat1 = procData(ci,:);
                
                plot(app.(['UIAxes_',num2str(ci)]),pdat1)
                
            end
            
        end
        
        function outDat = procdata(app , rawdata)
            
            switch app.curCHAN
                
                case 'LFP'
                    rawDatMat = double(cell2mat(rawdata));
                    comAve = rawDatMat - mean(rawDatMat);
                    outDat = comAve;
                otherwise
                    outDat = cell2mat(rawdata);
            end


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.curFILEnum = 1;
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
            if strcmp(app.ChannelTypeMenu.Enable,'off')
                return
            else
                
                switch key
                    
                    case 'leftarrow'
                        app.curFILEnum = app.curFILEnum - 1;
                        if app.curFILEnum < 1
                            app.curFILEnum = 1;
                            return
                        else
                            updatePLOTS(app)
                        end
                        
                    case 'rightarrow'
                        
                        app.curFILEnum = app.curFILEnum + 1;
                        if app.curFILEnum > length(app.eeglist)
                            app.curFILEnum = length(app.eeglist);
                            return
                        else
                            updatePLOTS(app)
                        end
                        
                    otherwise
                        return
                end
            end
            
        end

        % Menu selected function: LoadDate
        function LoadDateSelected(app, event)
            app.fileLOC = uigetdir();
            
            cd(app.fileLOC)
            
            mdir = dir('*.mat');
            mdir2 = {mdir.name};
            app.fileLIST = mdir2;
            % Use Matfile function to import the appropriate files
            
            matob = matfile('F210110-0001.mat');
            varlist = who(matob);
            app.eeglist = varlist(contains(varlist,'CEEG'));
%             ttllist = varlist(contains(varlist,'CDIG'));

            
            chanNum = 1:16;
            chanLab = {'EEG','EEG','LFP','EEG','EEG','EEG','LFP','EEG',...
                'EEG','LFP','EOG','EOG','EMG','EMG','EMG','LFP'};
            chanID = {'F3','Fz','D0','A1','C3','Cz','D1','A2','O1','D2',...
                'EOG1','EOG2','Chin1','Chin2','ChinZ','D3'};
            
            app.labTab = table(chanNum,chanLab,chanID,'VariableNames',{'Number',...
                'Label','ID'});

            app.ChannelTypeMenu.Enable = 'on';
        end

        % Menu selected function: EEGMenu
        function EEGMenuSelected(app, event)
            % Select channel ID
            app.curCHAN = 'EEG';
            % Update Graph number
            chanL = app.labTab.Label;
            app.GraphNum = sum(contains(chanL,app.curCHAN));
            % Update Plots
            updatePLOTS(app)
        end

        % Menu selected function: LFPMenu
        function LFPMenuSelected(app, event)
            % Select channel ID
            app.curCHAN = 'LFP';
            % Update Graph number
            chanL = app.labTab.Label;
            app.GraphNum = sum(contains(chanL,app.curCHAN));
            % Update Plots
            updatePLOTS(app)
        end

        % Menu selected function: EOGMenu
        function EOGMenuSelected(app, event)
            % Select channel ID
            app.curCHAN = 'EOG';
            % Update Graph number
            chanL = app.labTab.Label;
            app.GraphNum = sum(contains(chanL,app.curCHAN));
            % Update Plots
            updatePLOTS(app)
        end

        % Menu selected function: EMGMenu
        function EMGMenuSelected(app, event)
            % Select channel ID
            app.curCHAN = 'EMG';
            % Update Graph number
            chanL = app.labTab.Label;
            app.GraphNum = sum(contains(chanL,app.curCHAN));
            % Update Plots
            updatePLOTS(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.FileOptionsMenu = uimenu(app.UIFigure);
            app.FileOptionsMenu.Text = 'File Options';
            app.LoadDate = uimenu(app.FileOptionsMenu);
            app.LoadDate.MenuSelectedFcn = createCallbackFcn(app, @LoadDateSelected, true);
            app.LoadDate.Text = 'Load Date';
            app.ChannelTypeMenu = uimenu(app.UIFigure);
            app.ChannelTypeMenu.Enable = 'off';
            app.ChannelTypeMenu.Text = 'Channel Type';
            app.EEGMenu = uimenu(app.ChannelTypeMenu);
            app.EEGMenu.MenuSelectedFcn = createCallbackFcn(app, @EEGMenuSelected, true);
            app.EEGMenu.Text = 'EEG';
            app.LFPMenu = uimenu(app.ChannelTypeMenu);
            app.LFPMenu.MenuSelectedFcn = createCallbackFcn(app, @LFPMenuSelected, true);
            app.LFPMenu.Text = 'LFP';
            app.EOGMenu = uimenu(app.ChannelTypeMenu);
            app.EOGMenu.MenuSelectedFcn = createCallbackFcn(app, @EOGMenuSelected, true);
            app.EOGMenu.Text = 'EOG';
            app.EMGMenu = uimenu(app.ChannelTypeMenu);
            app.EMGMenu.MenuSelectedFcn = createCallbackFcn(app, @EMGMenuSelected, true);
            app.EMGMenu.Text = 'EMG';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes_1 = uiaxes(app.GridLayout);
            ylabel(app.UIAxes_1, 'Y')
            zlabel(app.UIAxes_1, 'Z')
            app.UIAxes_1.XTick = [];
            app.UIAxes_1.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.GridLayout);
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.Visible = 'off';
            app.UIAxes_3 = uiaxes(app.GridLayout);
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.Visible = 'off';
            app.UIAxes_4 = uiaxes(app.GridLayout);
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.Visible = 'off';
            app.UIAxes_5 = uiaxes(app.GridLayout);
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.Visible = 'off';
            app.UIAxes_6 = uiaxes(app.GridLayout);
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_6.XTick = [];
            app.UIAxes_6.Visible = 'off';
            app.UIAxes_7 = uiaxes(app.GridLayout);
            ylabel(app.UIAxes_7, 'Y')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_7.XTick = [];
            app.UIAxes_7.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SNSC_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end