classdef SNSC_Abv_App_02142021 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        LOAD                         matlab.ui.container.Menu
        LFPPanel                     matlab.ui.container.Panel
        GridLayout                   matlab.ui.container.GridLayout
        LFP1                         matlab.ui.control.UIAxes
        LFP2                         matlab.ui.control.UIAxes
        LFP3                         matlab.ui.control.UIAxes
        LFP4                         matlab.ui.control.UIAxes
        UniqueValsEditFieldLabel     matlab.ui.control.Label
        uLFP1                        matlab.ui.control.NumericEditField
        VarofsigEditFieldLabel       matlab.ui.control.Label
        vLFP1                        matlab.ui.control.NumericEditField
        NumberoffilesEditFieldLabel  matlab.ui.control.Label
        numfiles                     matlab.ui.control.NumericEditField
        MinutesofRecEditFieldLabel   matlab.ui.control.Label
        recDur                       matlab.ui.control.NumericEditField
        UniqueValsEditFieldLabel_2   matlab.ui.control.Label
        uLFP2                        matlab.ui.control.NumericEditField
        VarofsigEditFieldLabel_2     matlab.ui.control.Label
        vLFP2                        matlab.ui.control.NumericEditField
        UniqueValsEditFieldLabel_3   matlab.ui.control.Label
        uLFP3                        matlab.ui.control.NumericEditField
        UniqueValsEditFieldLabel_4   matlab.ui.control.Label
        uLFP4                        matlab.ui.control.NumericEditField
        VarofsigEditFieldLabel_3     matlab.ui.control.Label
        vLFP3                        matlab.ui.control.NumericEditField
        VarofsigEditFieldLabel_4     matlab.ui.control.Label
        vLFP4                        matlab.ui.control.NumericEditField
        PSDPanel                     matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        lfpp1                        matlab.ui.control.UIAxes
        lfpp2                        matlab.ui.control.UIAxes
        lfpp3                        matlab.ui.control.UIAxes
        lfpp4                        matlab.ui.control.UIAxes
        CEEGIDPanel                  matlab.ui.container.Panel
        ceegS2                       matlab.ui.control.DropDown
        ceegS3                       matlab.ui.control.DropDown
        ceegS1                       matlab.ui.control.DropDown
        ceegS4                       matlab.ui.control.DropDown
        Lamp1                        matlab.ui.control.Lamp
        Lamp2                        matlab.ui.control.Lamp
        Lamp3                        matlab.ui.control.Lamp
        Lamp4                        matlab.ui.control.Lamp
        CEEGIDSel                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        fileLOC % Description
        fileLIST
        eeglist
        labTab
        curCHAN
        allChan
        lfpPLOTceeg
        allLFP
    end
    
    methods (Access = private)
        
        function plotDATA(app)
            
            % START UPDATE PROGRESS BAR
            prog = uiprogressdlg(app.UIFigure,"Title","QC LFP",...
                "Indeterminate","on","Message","Processing LFP");
            drawnow
            
            % UPDATE to SHOW 
            prog.Message = "Computing common reference";
            drawnow
            % Extract LFP IDs
            LFPdat = zeros(4,length(app.allLFP.c1));
            for chI = 1:length(numel(fieldnames(app.allLFP)))
                LFPdat(chI,:) = app.allLFP.(['c',num2str(chI)]);
            end
            % Compute mean
            LFPmean = mean(LFPdat);
            
            % Sutract common reference
            LFPref = LFPdat - LFPmean;
            
            % UPDATE to SHOW
            prog.Message = "Computing values of interest";
            drawnow
            % Loop through LFP
            for li = 1:4
                
                tmpUv = numel(unique(LFPref(li,:)));
                app.(['uLFP',num2str(li)]).Value = tmpUv;
                
                tmpVar = var(LFPref(li,:));
                app.(['vLFP',num2str(li)]).Value = tmpVar;
                
            end
            
            % UPDATE to SHOW
            prog.Message = "Computing QC values";
            drawnow
            % Plot over Guassian
            for lp = 1:4
                
                lfpn = normalize(LFPref(lp,:),'scale');
                
                lfpnRs = randsample(lfpn, round(length(lfpn)*0.1), false);
                
                sdlfpn = std(lfpnRs);
                r = normrnd(0,sdlfpn,[1,length(lfpnRs)]);
                
                histogram(app.(['LFP',num2str(lp)]),r,'EdgeColor','none',...
                    'FaceAlpha',0.5,'FaceColor','g');
                hold(app.(['LFP',num2str(lp)]), 'on')
                histogram(app.(['LFP',num2str(lp)]),lfpnRs,'EdgeColor','none',...
                    'FaceAlpha',0.5,'FaceColor','k');
                
                legend(app.(['LFP',num2str(lp)]),'G','L')
                
            end
            
            % Plot PSD
            prog.Message = "Creating tables";
            drawnow
            TT1 = timetable(transpose(double(app.allLFP.c1)),'SampleRate',1375,'VariableName',["DO"]);
            TT2 = timetable(transpose(double(app.allLFP.c2)),'SampleRate',1375,'VariableName',["D1"]);
            TT3 = timetable(transpose(double(app.allLFP.c3)),'SampleRate',1375,'VariableName',["D2"]);
            TT4 = timetable(transpose(double(app.allLFP.c4)),'SampleRate',1375,'VariableName',["D3"]);
            
            allTTs = {TT1,TT2,TT3,TT4};
            

            for tti = 1:4
                
                [pxx,f] = pspectrum(allTTs{tti},"FrequencyLimits",[0 50]);
                plot(app.(['lfpp',num2str(tti)]),f,pow2db(pxx),'LineWidth',2)
                xlabel(app.(['lfpp',num2str(tti)]),'Frequency (Hz)')

            end
            
            % END PROGRESS BAR
            close(prog)
            finalCHECK(app)
            
        end


        function checkCEEGlfp(app)
            
            app.lfpPLOTceeg = zeros(4,1);
            for li = 1:4
                app.lfpPLOTceeg(li) = str2double(app.(['ceegS',num2str(li)]).Value);
            end
            
        end
        
        function finalCHECK(app)
            
            for li = 1:4
                
                if app.(['uLFP',num2str(li)]).Value > 50
                    uniqVal = 1;
                end
                
                if app.(['vLFP',num2str(li)]).Value > 0.01
                    varVal = 1;
                end
                
                if app.recDur.Value > 2
                    durVal = 1;
                end
                
                if uniqVal && varVal && durVal
                    app.(['Lamp',num2str(li)]).Color = 'g';
                end
                
            end
             
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            uialert(app.UIFigure,'SELECT the CEEG Channels','LFP QC Program','Icon',"info")
            
        end

        % Button pushed function: CEEGIDSel
        function CEEGIDSelButtonPushed(app, event)
            
            checkCEEGlfp(app)
            
            uialert(app.UIFigure,'PRESS LOAD Button','LFP QC Program','Icon',"info")
            
            app.LOAD.Enable = 'on';
            
        end

        % Menu selected function: LOAD
        function LOADMenuSelected(app, event)
            app.fileLOC = uigetdir();
            
            cd(app.fileLOC)
            
            mdir = dir('*.mat');
            mdir2 = {mdir.name};
            app.fileLIST = mdir2;
            % Use Matfile function to import the appropriate files
            
            matob = matfile('F210110-0001.mat');
            varlist = who(matob);
            app.eeglist = varlist(contains(varlist,'CEEG'));
            
            uniCEEG1 = cellfun(@(x) strsplit(x,'_'), app.eeglist, 'UniformOutput', false);
            uniCEEG2 = cellfun(@(x) str2double(x{3}), uniCEEG1, 'UniformOutput',true);

            % Combine first 3 files
            app.allChan = struct;
            for fi = 1:3
                
                curFile = app.fileLIST{fi};
                
                load(curFile,app.eeglist{:});

                for ni = 1:4
                    
                    tmpChan = ismember(uniCEEG2,app.lfpPLOTceeg(ni));
                    tmpRows = app.eeglist(tmpChan);
                    rawRow = tmpRows{1};
                    
                    dat1 = eval(rawRow);
                    
                    if ~isfield(app.allLFP,['c',num2str(ni)])
                        app.allLFP.(['c',num2str(ni)]) = dat1;
                    else
                        app.allLFP.(['c',num2str(ni)]) = [app.allLFP.(['c',num2str(ni)]) , dat1];
                    end
                    
                end
                
                
            end
            % Extract recording information
            app.numfiles.Value = numel(app.fileLIST);
            
            totTime = 0;
            uniCEEG1 = cellfun(@(x) strsplit(x,'_'), app.eeglist, 'UniformOutput', false);
            uniCEEG2 = cellfun(@(x) str2double(x{3}), uniCEEG1, 'UniformOutput',true);
            
            for ff = 1:length(app.fileLIST)
                
                curF = app.fileLIST{ff};
                load(curF,app.eeglist{:});
                
                tmpChan = ismember(uniCEEG2,app.lfpPLOTceeg(1));
                tmpRows = app.eeglist(tmpChan);
                tbegint = tmpRows{6};
                tendt = tmpRows{7};
                
                Tbeg = eval(tbegint);
                Tend = eval(tendt);
                
                tmpTime = Tend - Tbeg;
                
                totTime = totTime + tmpTime;

            end
            app.recDur.Value = (round(totTime)/60);
            
            % Update plots
            plotDATA(app)
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LOAD = uimenu(app.UIFigure);
            app.LOAD.MenuSelectedFcn = createCallbackFcn(app, @LOADMenuSelected, true);
            app.LOAD.Enable = 'off';
            app.LOAD.Text = 'LOAD';
            app.LFPPanel = uipanel(app.UIFigure);
            app.LFPPanel.Title = 'LFP';
            app.GridLayout = uigridlayout(app.LFPPanel);
            app.LFP1 = uiaxes(app.GridLayout);
            zlabel(app.LFP1, 'Z')
            app.LFP1.XTick = [];
            app.LFP1.YTick = [];
            app.LFP2 = uiaxes(app.GridLayout);
            zlabel(app.LFP2, 'Z')
            app.LFP2.XTick = [];
            app.LFP2.YTick = [];
            app.LFP3 = uiaxes(app.GridLayout);
            zlabel(app.LFP3, 'Z')
            app.LFP3.XTick = [];
            app.LFP3.YTick = [];
            app.LFP4 = uiaxes(app.GridLayout);
            zlabel(app.LFP4, 'Z')
            app.LFP4.XTick = [];
            app.LFP4.YTick = [];
            app.UniqueValsEditFieldLabel = uilabel(app.UIFigure);
            app.UniqueValsEditFieldLabel.Text = '# Unique Vals';
            app.uLFP1 = uieditfield(app.UIFigure, 'numeric');
            app.VarofsigEditFieldLabel = uilabel(app.UIFigure);
            app.VarofsigEditFieldLabel.Text = 'Var. of sig';
            app.vLFP1 = uieditfield(app.UIFigure, 'numeric');
            app.NumberoffilesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberoffilesEditFieldLabel.Text = 'Number of files = ';
            app.numfiles = uieditfield(app.UIFigure, 'numeric');
            app.numfiles.Editable = 'off';
            app.MinutesofRecEditFieldLabel = uilabel(app.UIFigure);
            app.MinutesofRecEditFieldLabel.Text = 'Minutes of Rec. = ';
            app.recDur = uieditfield(app.UIFigure, 'numeric');
            app.recDur.Editable = 'off';
            app.UniqueValsEditFieldLabel_2 = uilabel(app.UIFigure);
            app.UniqueValsEditFieldLabel_2.Text = '# Unique Vals';
            app.uLFP2 = uieditfield(app.UIFigure, 'numeric');
            app.VarofsigEditFieldLabel_2 = uilabel(app.UIFigure);
            app.VarofsigEditFieldLabel_2.Text = 'Var. of sig';
            app.vLFP2 = uieditfield(app.UIFigure, 'numeric');
            app.UniqueValsEditFieldLabel_3 = uilabel(app.UIFigure);
            app.UniqueValsEditFieldLabel_3.Text = '# Unique Vals';
            app.uLFP3 = uieditfield(app.UIFigure, 'numeric');
            app.UniqueValsEditFieldLabel_4 = uilabel(app.UIFigure);
            app.UniqueValsEditFieldLabel_4.Text = '# Unique Vals';
            app.uLFP4 = uieditfield(app.UIFigure, 'numeric');
            app.VarofsigEditFieldLabel_3 = uilabel(app.UIFigure);
            app.VarofsigEditFieldLabel_3.Text = 'Var. of sig';
            app.vLFP3 = uieditfield(app.UIFigure, 'numeric');
            app.VarofsigEditFieldLabel_4 = uilabel(app.UIFigure);
            app.VarofsigEditFieldLabel_4.Text = 'Var. of sig';
            app.vLFP4 = uieditfield(app.UIFigure, 'numeric');
            app.PSDPanel = uipanel(app.UIFigure);
            app.PSDPanel.Title = 'PSD';
            app.GridLayout2 = uigridlayout(app.PSDPanel);
            app.lfpp1 = uiaxes(app.GridLayout2);
            app.lfpp1.XTick = [1 10 20 30 40 50];
            app.lfpp1.XTickLabel = {'1'; '10'; '20'; '30'; '40'; '50'};
            app.lfpp1.YTick = [];
            app.lfpp2 = uiaxes(app.GridLayout2);
            app.lfpp2.XTick = [1 10 20 30 40 50];
            app.lfpp2.XTickLabel = {'1'; '10'; '20'; '30'; '40'; '50'};
            app.lfpp2.YTick = [];
            app.lfpp3 = uiaxes(app.GridLayout2);
            app.lfpp3.XTick = [1 10 20 30 40 50];
            app.lfpp3.XTickLabel = {'1'; '10'; '20'; '30'; '40'; '50'};
            app.lfpp3.YTick = [];
            app.lfpp4 = uiaxes(app.GridLayout2);
            app.lfpp4.XTick = [1 10 20 30 40 50];
            app.lfpp4.XTickLabel = {'1'; '10'; '20'; '30'; '40'; '50'};
            app.lfpp4.YTick = [];
            app.CEEGIDPanel = uipanel(app.UIFigure);
            app.CEEGIDPanel.Title = 'CEEG ID';
            app.ceegS2 = uidropdown(app.CEEGIDPanel);
            app.ceegS2.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20'};
            app.ceegS2.Value = '1';
            app.ceegS3 = uidropdown(app.CEEGIDPanel);
            app.ceegS3.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20'};
            app.ceegS3.Value = '1';
            app.ceegS1 = uidropdown(app.CEEGIDPanel);
            app.ceegS1.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20'};
            app.ceegS1.Value = '1';
            app.ceegS4 = uidropdown(app.CEEGIDPanel);
            app.ceegS4.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20'};
            app.ceegS4.Value = '1';
            app.Lamp1 = uilamp(app.CEEGIDPanel);
            app.Lamp2 = uilamp(app.CEEGIDPanel);
            app.Lamp3 = uilamp(app.CEEGIDPanel);
            app.Lamp4 = uilamp(app.CEEGIDPanel);
            app.CEEGIDSel = uibutton(app.UIFigure, 'push');
            app.CEEGIDSel.ButtonPushedFcn = createCallbackFcn(app, @CEEGIDSelButtonPushed, true);
            app.CEEGIDSel.Text = 'CEEG ID Sel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SNSC_Abv_App_02142021

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end