classdef SignalQC_v001_Tabs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LOAD                            matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        LFPTab                          matlab.ui.container.Tab
        MinutesofRecEditFieldLabel      matlab.ui.control.Label
        recDur                          matlab.ui.control.NumericEditField
        SDofsignalLabel                 matlab.ui.control.Label
        vLFP4                           matlab.ui.control.NumericEditField
        UniqueValuesLabel               matlab.ui.control.Label
        uLFP4                           matlab.ui.control.NumericEditField
        UniqueValuesLabel_2             matlab.ui.control.Label
        uLFP3                           matlab.ui.control.NumericEditField
        SDofsignalLabel_2               matlab.ui.control.Label
        vLFP3                           matlab.ui.control.NumericEditField
        UniqueValuesLabel_3             matlab.ui.control.Label
        uLFP2                           matlab.ui.control.NumericEditField
        SDofsignalLabel_3               matlab.ui.control.Label
        vLFP2                           matlab.ui.control.NumericEditField
        SDofsignalREF250Label           matlab.ui.control.Label
        vLFP1                           matlab.ui.control.NumericEditField
        UniqueValuesREF535Label         matlab.ui.control.Label
        uLFP1                           matlab.ui.control.NumericEditField
        LFPPanel                        matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        LFP1                            matlab.ui.control.UIAxes
        LFP2                            matlab.ui.control.UIAxes
        LFP3                            matlab.ui.control.UIAxes
        LFP4                            matlab.ui.control.UIAxes
        PSDPanel                        matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        lfpp1                           matlab.ui.control.UIAxes
        lfpp2                           matlab.ui.control.UIAxes
        lfpp3                           matlab.ui.control.UIAxes
        lfpp4                           matlab.ui.control.UIAxes
        Nyquist                         matlab.ui.control.StateButton
        CEEGIDPanel                     matlab.ui.container.Panel
        Lamp1                           matlab.ui.control.Lamp
        Lamp2                           matlab.ui.control.Lamp
        Lamp3                           matlab.ui.control.Lamp
        Lamp4                           matlab.ui.control.Lamp
        EEGTab                          matlab.ui.container.Tab
        UniquevaluesPanel               matlab.ui.container.Panel
        EEG1UniqueValuesEditFieldLabel  matlab.ui.control.Label
        eegU1                           matlab.ui.control.EditField
        EEG2UVEditFieldLabel            matlab.ui.control.Label
        eegU2                           matlab.ui.control.EditField
        EEG3UVLabel                     matlab.ui.control.Label
        eegU3                           matlab.ui.control.EditField
        EEG4UVLabel                     matlab.ui.control.Label
        eegU4                           matlab.ui.control.EditField
        EEG5UVLabel                     matlab.ui.control.Label
        eegU5                           matlab.ui.control.EditField
        EEG6UVLabel                     matlab.ui.control.Label
        eegU6                           matlab.ui.control.EditField
        EEG7UVLabel                     matlab.ui.control.Label
        eegU7                           matlab.ui.control.EditField
        EEG8UVLabel                     matlab.ui.control.Label
        eegU8                           matlab.ui.control.EditField
        EEG9UVLabel                     matlab.ui.control.Label
        eegU9                           matlab.ui.control.EditField
        EEG10UVLabel                    matlab.ui.control.Label
        eegU10                          matlab.ui.control.EditField
        EEG11UVLabel                    matlab.ui.control.Label
        eegU11                          matlab.ui.control.EditField
        EEGplotsPanel                   matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        eeg1                            matlab.ui.control.UIAxes
        eeg2                            matlab.ui.control.UIAxes
        eeg3                            matlab.ui.control.UIAxes
        eeg4                            matlab.ui.control.UIAxes
        eeg5                            matlab.ui.control.UIAxes
        eeg6                            matlab.ui.control.UIAxes
        eeg7                            matlab.ui.control.UIAxes
        eeg8                            matlab.ui.control.UIAxes
        eeg9                            matlab.ui.control.UIAxes
        eeg10                           matlab.ui.control.UIAxes
        eeg11                           matlab.ui.control.UIAxes
        EOGTab                          matlab.ui.container.Tab
        UniqueValuesPanel               matlab.ui.container.Panel
        UniqueValuesEOG1EditFieldLabel  matlab.ui.control.Label
        eogU1                           matlab.ui.control.NumericEditField
        UniqueValuesEOG2EditFieldLabel  matlab.ui.control.Label
        eogU2                           matlab.ui.control.NumericEditField
        EOGPanel                        matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        eog1                            matlab.ui.control.UIAxes
        eog2                            matlab.ui.control.UIAxes
        EMGTab                          matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        UniqueValuesEMG1EditFieldLabel  matlab.ui.control.Label
        emgU1                           matlab.ui.control.NumericEditField
        UniqueValuesEMG2EditFieldLabel  matlab.ui.control.Label
        emgU2                           matlab.ui.control.NumericEditField
        UniqueValuesEMG3EditFieldLabel  matlab.ui.control.Label
        emgU3                           matlab.ui.control.NumericEditField
        Panel2                          matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        emg1                            matlab.ui.control.UIAxes
        emg2                            matlab.ui.control.UIAxes
        emg3                            matlab.ui.control.UIAxes
    end

    % REVISION HISTORY:
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    % Revision 02/18/2021 - 11:57AM
    % 1. Remove guassian distribution
    % 2. Add label for LFP data


    properties (Access = private)
        fileLOC % Description
        fileLIST
        eeglist
        labTab
        curCHAN
        allChan
        lfpPLOTceeg
        allLFP
        Usefile
        allTTsf
        allEEG
        allEOG
        allEMG
    end
    
    methods (Access = private)
        
        function plotDATA(app)
            
            % START UPDATE PROGRESS BAR
            prog = uiprogressdlg(app.UIFigure,"Title","QC LFP",...
                "Indeterminate","on","Message","Processing LFP");
            drawnow
            
            % UPDATE to SHOW 
            prog.Message = "Computing common reference";
            drawnow
            % Extract LFP IDs
            LFPdat = zeros(4,length(app.allLFP.c1));
            for chI = 1:numel(fieldnames(app.allLFP))
                chIi = chI - 1;
                LFPdat(chI,:) = app.allLFP.(['c',num2str(chIi)]);
            end
            % Compute mean
            LFPmean = mean(LFPdat);
            
            % Sutract common reference
            LFPref = LFPdat - LFPmean;
            
            % UPDATE to SHOW
            prog.Message = "Computing values of interest";
            drawnow
            % Loop through LFP
            for li = 1:4
                
                % Should be between 5-50
                tmpUv = round((numel(unique(LFPref(li,:)))/2000)*100,1);
                app.(['uLFP',num2str(li)]).Value = tmpUv;
                
                % Should be between 5-100
                tmpVar = round(std(LFPref(li,:)));
                app.(['vLFP',num2str(li)]).Value = tmpVar;
                
            end
            
            % UPDATE to SHOW
            prog.Message = "Computing QC values";
            drawnow
            % Plot over Guassian
            for lp = 1:4
                
                lfpn = normalize(LFPref(lp,:),'scale');
                
                lfpnRs = randsample(lfpn, round(length(lfpn)*0.1), false);

                histogram(app.(['LFP',num2str(lp)]),lfpnRs,'EdgeColor','none',...
                    'FaceAlpha',0.5,'FaceColor','k');
                
                
                legend(app.(['LFP',num2str(lp)]),['Channel' , num2str(lp)])
                
            end
            
            % Plot PSD
            prog.Message = "Creating tables";
            drawnow
            TT1f = timetable(transpose(double(app.allLFP.c0)),'SampleRate',1375,'VariableName',"DO");
            TT2f = timetable(transpose(double(app.allLFP.c1)),'SampleRate',1375,'VariableName',"D1");
            TT3f = timetable(transpose(double(app.allLFP.c2)),'SampleRate',1375,'VariableName',"D2");
            TT4f = timetable(transpose(double(app.allLFP.c3)),'SampleRate',1375,'VariableName',"D3");

            app.allTTsf = {TT1f,TT2f,TT3f,TT4f};
            

            for tti = 1:4
                
                [pxx,f] = pspectrum(app.allTTsf{tti},"FrequencyLimits",[0 50]);
                plot(app.(['lfpp',num2str(tti)]),f,pow2db(pxx),'LineWidth',2)
                xlabel(app.(['lfpp',num2str(tti)]),'Frequency (Hz)')
                ylabel(app.(['lfpp',num2str(tti)]),'PowerSpec (dB)')

            end
            
            % END PROGRESS BAR
            close(prog)
            finalCHECK(app)
            
            app.Nyquist.Enable = 'on';
            
            %% For EEG panel
            for eegi = 1:11
                
                eegT = app.allEEG.(['eeg',num2str(eegi)]);
                
                % Should be between 5-50
                tmpUv = round((numel(unique(eegT))/2000)*100,1);
                app.(['eegU',num2str(eegi)]).Value = num2str(tmpUv);
            end
            
            % EEG plot
            for eegi = 1:11
                plot(app.(['eeg',num2str(eegi)]),app.allEEG.(['eeg',num2str(eegi)]),'LineWidth',2)
            end
            
            %% For EOG panel
            for eogi = 1:2
                
                eogT = app.allEOG.(['eog',num2str(eogi)]);
                
                % Should be between 5-50
                tmpUv = round((numel(unique(eogT))/2000)*100,1);
                app.(['eogU',num2str(eogi)]).Value = tmpUv;
            end
            
            % EOG plot
            for eogi = 1:2
                plot(app.(['eog',num2str(eogi)]),app.allEOG.(['eog',num2str(eogi)]),'LineWidth',2)
            end
            
            %% For EMG panel
            for emgi = 1:3
                
                emgT = app.allEMG.(['emg',num2str(emgi)]);
                
                % Should be between 5-50
                tmpUv = round((numel(unique(emgT))/2000)*100,1);
                app.(['emgU',num2str(emgi)]).Value = tmpUv;
            end
            
            % EMG plot
            for emgi = 1:3
                plot(app.(['emg',num2str(emgi)]),app.allEMG.(['emg',num2str(emgi)]),'LineWidth',2)
            end
            
        end
        

        function finalCHECK(app)
            
            for li = 1:4
                
                if app.(['uLFP',num2str(li)]).Value > 1
                    uniqVal = 1;
                else 
                    uniqVal = 0;
                end
                
                if app.(['vLFP',num2str(li)]).Value > 1
                    varVal = 1;
                else
                    varVal = 0;
                end
                
                if app.recDur.Value > 2
                    durVal = 1;
                else
                    durVal = 0;
                end
                
                if uniqVal && varVal && durVal
                    app.(['Lamp',num2str(li)]).Color = 'g';
                end
                
            end
             
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: LOAD
        function LOADMenuSelected(app, event)
            
            [app.Usefile,app.fileLOC] = uigetfile('*.mat');
          
            cd(app.fileLOC)
            
            % Combine first 3 files
            app.allChan = struct;
            curFile = app.Usefile;
            
            % LFP
            for ni = 1:4
                nii = ni - 1;
                
                load(curFile,['CDBS_',num2str(nii)]);
                dat1 = eval(['CDBS_',num2str(nii)]);
                app.allLFP.(['c',num2str(nii)]) = dat1.Samples;
                
            end
            
            % EEG
            eeGs = {'F3','Fz','F4','A1','C3','Cz','C4','A2','O1','Oz','O2'};
            for eegi = 1:11
                
                if length(num2str(eegi)) == 1
                    curName = ['CEEG_2___0',num2str(eegi),'___',eeGs{eegi}];
                    load(curFile,curName)
                else
                    curName = ['CEEG_2___',num2str(eegi),'___',eeGs{eegi}];
                    load(curFile,curName)
                end
                dat1 = eval(curName);
                app.allEEG.(['eeg',num2str(eegi)]) = dat1.Samples;
            end
            
           % EOG
            for eogi = 1:2
                curName = ['CEOG_',num2str(eogi)];
                load(curFile,curName)
                
                dat1 = eval(curName);
                app.allEOG.(['eog',num2str(eogi)]) = dat1.Samples; 
            end
            
            % EMG
            emGs = {'CChin_1','CChin_2','CChin_Z'};
            for emgi = 1:3
                curName = emGs{emgi};
                load(curFile,curName)
                
                dat1 = eval(curName);
                app.allEMG.(['emg',num2str(emgi)]) = dat1.Samples; 
            end
            
            % Extract recording information          
            load(curFile,['CDBS_',num2str(0)]);
            timeall = eval(['CDBS_',num2str(0)]);
            tbegint = timeall.TimeBegin;
            tendt = timeall.TimeEnd;

            tmpTime = tendt - tbegint;

            app.recDur.Value = (round(tmpTime)/60);
            
            % Update plots
            plotDATA(app)
            
            
        end

        % Value changed function: Nyquist
        function NyquistValueChanged(app, event)
            value = app.Nyquist.Value;
            
            if value
                for tti = 1:4
                    [pxx,f] = pspectrum(app.allTTsf{tti},"FrequencyLimits",[0 round(1375/2)]);
                    plot(app.(['lfpp',num2str(tti)]),f,pow2db(pxx),'LineWidth',2)
                    xlabel(app.(['lfpp',num2str(tti)]),'Frequency (Hz)')
                    ylabel(app.(['lfpp',num2str(tti)]),'PowerSpec (dB)')

                    
                end
            else
                for tti = 1:4
                    [pxx,f] = pspectrum(app.allTTsf{tti},"FrequencyLimits",[0 50]);
                    plot(app.(['lfpp',num2str(tti)]),f,pow2db(pxx),'LineWidth',2)
                    xlabel(app.(['lfpp',num2str(tti)]),'Frequency (Hz)')
                    ylabel(app.(['lfpp',num2str(tti)]),'PowerSpec (dB)')

                end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LOAD = uimenu(app.UIFigure);
            app.LOAD.MenuSelectedFcn = createCallbackFcn(app, @LOADMenuSelected, true);
            app.LOAD.Text = 'LOAD';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LFPTab = uitab(app.TabGroup);
            app.LFPTab.Title = 'LFP';
            app.MinutesofRecEditFieldLabel = uilabel(app.LFPTab);
            app.MinutesofRecEditFieldLabel.Text = 'Minutes of Rec. = ';
            app.recDur = uieditfield(app.LFPTab, 'numeric');
            app.recDur.Editable = 'off';
            app.SDofsignalLabel = uilabel(app.LFPTab);
            app.SDofsignalLabel.Text = 'SD of signal';
            app.vLFP4 = uieditfield(app.LFPTab, 'numeric');
            app.UniqueValuesLabel = uilabel(app.LFPTab);
            app.UniqueValuesLabel.Text = '% Unique Values';
            app.uLFP4 = uieditfield(app.LFPTab, 'numeric');
            app.UniqueValuesLabel_2 = uilabel(app.LFPTab);
            app.UniqueValuesLabel_2.Text = '% Unique Values';
            app.uLFP3 = uieditfield(app.LFPTab, 'numeric');
            app.SDofsignalLabel_2 = uilabel(app.LFPTab);
            app.SDofsignalLabel_2.Text = 'SD of signal';
            app.vLFP3 = uieditfield(app.LFPTab, 'numeric');
            app.UniqueValuesLabel_3 = uilabel(app.LFPTab);
            app.UniqueValuesLabel_3.Text = '% Unique Values';
            app.uLFP2 = uieditfield(app.LFPTab, 'numeric');
            app.SDofsignalLabel_3 = uilabel(app.LFPTab);
            app.SDofsignalLabel_3.Text = 'SD of signal';
            app.vLFP2 = uieditfield(app.LFPTab, 'numeric');
            app.SDofsignalREF250Label = uilabel(app.LFPTab);
            app.SDofsignalREF250Label.Text = {'SD of signal'; 'REF: 2-50'};
            app.vLFP1 = uieditfield(app.LFPTab, 'numeric');
            app.UniqueValuesREF535Label = uilabel(app.LFPTab);
            app.UniqueValuesREF535Label.Text = {'% Unique Values'; 'REF: 5-35'};
            app.uLFP1 = uieditfield(app.LFPTab, 'numeric');
            app.LFPPanel = uipanel(app.LFPTab);
            app.LFPPanel.Title = 'LFP';
            app.GridLayout = uigridlayout(app.LFPPanel);
            app.LFP1 = uiaxes(app.GridLayout);
            xlabel(app.LFP1, 'Scaled values')
            ylabel(app.LFP1, 'Count')
            zlabel(app.LFP1, 'Z')
            app.LFP2 = uiaxes(app.GridLayout);
            xlabel(app.LFP2, 'Scaled values')
            ylabel(app.LFP2, 'Count')
            zlabel(app.LFP2, 'Z')
            app.LFP3 = uiaxes(app.GridLayout);
            xlabel(app.LFP3, 'Scaled values')
            ylabel(app.LFP3, 'Count')
            zlabel(app.LFP3, 'Z')
            app.LFP4 = uiaxes(app.GridLayout);
            xlabel(app.LFP4, 'Scaled values')
            ylabel(app.LFP4, 'Count')
            zlabel(app.LFP4, 'Z')
            app.PSDPanel = uipanel(app.LFPTab);
            app.PSDPanel.Title = 'PSD';
            app.GridLayout2 = uigridlayout(app.PSDPanel);
            app.lfpp1 = uiaxes(app.GridLayout2);
            app.lfpp2 = uiaxes(app.GridLayout2);
            app.lfpp3 = uiaxes(app.GridLayout2);
            app.lfpp4 = uiaxes(app.GridLayout2);
            app.Nyquist = uibutton(app.LFPTab, 'state');
            app.Nyquist.ValueChangedFcn = createCallbackFcn(app, @NyquistValueChanged, true);
            app.Nyquist.Enable = 'off';
            app.Nyquist.Text = 'Nyquist';
            app.CEEGIDPanel = uipanel(app.LFPTab);
            app.CEEGIDPanel.Title = 'CEEG ID';
            app.Lamp1 = uilamp(app.CEEGIDPanel);
            app.Lamp2 = uilamp(app.CEEGIDPanel);
            app.Lamp3 = uilamp(app.CEEGIDPanel);
            app.Lamp4 = uilamp(app.CEEGIDPanel);
            app.EEGTab = uitab(app.TabGroup);
            app.EEGTab.Title = 'EEG';
            app.UniquevaluesPanel = uipanel(app.EEGTab);
            app.UniquevaluesPanel.Title = 'Unique values';
            app.EEG1UniqueValuesEditFieldLabel = uilabel(app.UniquevaluesPanel);
            app.EEG1UniqueValuesEditFieldLabel.Text = 'EEG1 Unique Values';
            app.eegU1 = uieditfield(app.UniquevaluesPanel, 'text');
            app.EEG2UVEditFieldLabel = uilabel(app.UniquevaluesPanel);
            app.EEG2UVEditFieldLabel.Text = 'EEG2 UV %';
            app.eegU2 = uieditfield(app.UniquevaluesPanel, 'text');
            app.EEG3UVLabel = uilabel(app.UniquevaluesPanel);
            app.EEG3UVLabel.Text = 'EEG3 UV %';
            app.eegU3 = uieditfield(app.UniquevaluesPanel, 'text');
            app.EEG4UVLabel = uilabel(app.UniquevaluesPanel);
            app.EEG4UVLabel.Text = 'EEG4 UV %';
            app.eegU4 = uieditfield(app.UniquevaluesPanel, 'text');
            app.EEG5UVLabel = uilabel(app.UniquevaluesPanel);
            app.EEG5UVLabel.Text = 'EEG5 UV %';
            app.eegU5 = uieditfield(app.UniquevaluesPanel, 'text');
            app.EEG6UVLabel = uilabel(app.UniquevaluesPanel);
            app.EEG6UVLabel.Text = 'EEG6 UV %';
            app.eegU6 = uieditfield(app.UniquevaluesPanel, 'text');
            app.EEG7UVLabel = uilabel(app.UniquevaluesPanel);
            app.EEG7UVLabel.Text = 'EEG7 UV %';
            app.eegU7 = uieditfield(app.UniquevaluesPanel, 'text');
            app.EEG8UVLabel = uilabel(app.UniquevaluesPanel);
            app.EEG8UVLabel.Text = 'EEG8 UV %';
            app.eegU8 = uieditfield(app.UniquevaluesPanel, 'text');
            app.EEG9UVLabel = uilabel(app.UniquevaluesPanel);
            app.EEG9UVLabel.Text = 'EEG9 UV %';
            app.eegU9 = uieditfield(app.UniquevaluesPanel, 'text');
            app.EEG10UVLabel = uilabel(app.UniquevaluesPanel);
            app.EEG10UVLabel.Text = 'EEG10 UV %';
            app.eegU10 = uieditfield(app.UniquevaluesPanel, 'text');
            app.EEG11UVLabel = uilabel(app.UniquevaluesPanel);
            app.EEG11UVLabel.Text = 'EEG11 UV %';
            app.eegU11 = uieditfield(app.UniquevaluesPanel, 'text');
            app.EEGplotsPanel = uipanel(app.EEGTab);
            app.EEGplotsPanel.Title = 'EEG plots';
            app.GridLayout3 = uigridlayout(app.EEGplotsPanel);
            app.eeg1 = uiaxes(app.GridLayout3);
            zlabel(app.eeg1, 'Z')
            app.eeg1.XTick = [];
            app.eeg1.XTickLabel = '';
            app.eeg1.YTick = [];
            app.eeg2 = uiaxes(app.GridLayout3);
            zlabel(app.eeg2, 'Z')
            app.eeg2.XTick = [];
            app.eeg2.YTick = [];
            app.eeg2.YTickLabel = '';
            app.eeg3 = uiaxes(app.GridLayout3);
            zlabel(app.eeg3, 'Z')
            app.eeg3.XTick = [];
            app.eeg3.YTick = [];
            app.eeg4 = uiaxes(app.GridLayout3);
            zlabel(app.eeg4, 'Z')
            app.eeg4.XTick = [];
            app.eeg4.YTick = [];
            app.eeg5 = uiaxes(app.GridLayout3);
            zlabel(app.eeg5, 'Z')
            app.eeg5.XTick = [];
            app.eeg5.YTick = [];
            app.eeg6 = uiaxes(app.GridLayout3);
            zlabel(app.eeg6, 'Z')
            app.eeg6.XTick = [];
            app.eeg6.YTick = [];
            app.eeg7 = uiaxes(app.GridLayout3);
            zlabel(app.eeg7, 'Z')
            app.eeg7.XTick = [];
            app.eeg7.XTickLabel = '';
            app.eeg7.YTick = [];
            app.eeg8 = uiaxes(app.GridLayout3);
            zlabel(app.eeg8, 'Z')
            app.eeg8.XTick = [];
            app.eeg8.YTick = [];
            app.eeg9 = uiaxes(app.GridLayout3);
            zlabel(app.eeg9, 'Z')
            app.eeg9.XTick = [];
            app.eeg9.YTick = [];
            app.eeg10 = uiaxes(app.GridLayout3);
            zlabel(app.eeg10, 'Z')
            app.eeg10.XTick = [];
            app.eeg10.YTick = [];
            app.eeg11 = uiaxes(app.GridLayout3);
            zlabel(app.eeg11, 'Z')
            app.eeg11.XTick = [];
            app.eeg11.YTick = [];
            app.EOGTab = uitab(app.TabGroup);
            app.EOGTab.Title = 'EOG';
            app.UniqueValuesPanel = uipanel(app.EOGTab);
            app.UniqueValuesPanel.Title = 'Unique Values';
            app.UniqueValuesEOG1EditFieldLabel = uilabel(app.UniqueValuesPanel);
            app.UniqueValuesEOG1EditFieldLabel.Text = 'Unique Values EOG1';
            app.eogU1 = uieditfield(app.UniqueValuesPanel, 'numeric');
            app.UniqueValuesEOG2EditFieldLabel = uilabel(app.UniqueValuesPanel);
            app.UniqueValuesEOG2EditFieldLabel.Text = 'Unique Values EOG2';
            app.eogU2 = uieditfield(app.UniqueValuesPanel, 'numeric');
            app.EOGPanel = uipanel(app.EOGTab);
            app.EOGPanel.Title = 'EOG';
            app.GridLayout4 = uigridlayout(app.EOGPanel);
            app.eog1 = uiaxes(app.GridLayout4);
            zlabel(app.eog1, 'Z')
            app.eog1.XTick = [];
            app.eog1.YTick = [];
            app.eog2 = uiaxes(app.GridLayout4);
            zlabel(app.eog2, 'Z')
            app.eog2.XTick = [];
            app.eog2.YTick = [];
            app.EMGTab = uitab(app.TabGroup);
            app.EMGTab.Title = 'EMG';
            app.Panel = uipanel(app.EMGTab);
            app.Panel.Title = 'Panel';
            app.UniqueValuesEMG1EditFieldLabel = uilabel(app.Panel);
            app.UniqueValuesEMG1EditFieldLabel.Text = 'Unique Values EMG1';
            app.emgU1 = uieditfield(app.Panel, 'numeric');
            app.UniqueValuesEMG2EditFieldLabel = uilabel(app.Panel);
            app.UniqueValuesEMG2EditFieldLabel.Text = 'Unique Values EMG2';
            app.emgU2 = uieditfield(app.Panel, 'numeric');
            app.UniqueValuesEMG3EditFieldLabel = uilabel(app.Panel);
            app.UniqueValuesEMG3EditFieldLabel.Text = 'Unique Values EMG3';
            app.emgU3 = uieditfield(app.Panel, 'numeric');
            app.Panel2 = uipanel(app.EMGTab);
            app.Panel2.Title = 'Panel2';
            app.GridLayout5 = uigridlayout(app.Panel2);
            app.emg1 = uiaxes(app.GridLayout5);
            zlabel(app.emg1, 'Z')
            app.emg1.XTick = [];
            app.emg1.YTick = [];
            app.emg2 = uiaxes(app.GridLayout5);
            zlabel(app.emg2, 'Z')
            app.emg2.XTick = [];
            app.emg2.YTick = [];
            app.emg3 = uiaxes(app.GridLayout5);
            zlabel(app.emg3, 'Z')
            app.emg3.XTick = [];
            app.emg3.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignalQC_v001_Tabs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end