classdef SignalQC_v002_Tabs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LOAD                            matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        LFPTab                          matlab.ui.container.Tab
        MinutesofRecEditFieldLabel      matlab.ui.control.Label
        recDur                          matlab.ui.control.NumericEditField
        SDofsignalLabel                 matlab.ui.control.Label
        vLFP4                           matlab.ui.control.NumericEditField
        UniqueValuesLabel               matlab.ui.control.Label
        uLFP4                           matlab.ui.control.NumericEditField
        UniqueValuesLabel_2             matlab.ui.control.Label
        uLFP3                           matlab.ui.control.NumericEditField
        SDofsignalLabel_2               matlab.ui.control.Label
        vLFP3                           matlab.ui.control.NumericEditField
        UniqueValuesLabel_3             matlab.ui.control.Label
        uLFP2                           matlab.ui.control.NumericEditField
        SDofsignalLabel_3               matlab.ui.control.Label
        vLFP2                           matlab.ui.control.NumericEditField
        SDofsignalREF2500Label          matlab.ui.control.Label
        vLFP1                           matlab.ui.control.NumericEditField
        UniqueValuesREF5200Label        matlab.ui.control.Label
        uLFP1                           matlab.ui.control.NumericEditField
        LFPPanel                        matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        LFP1                            matlab.ui.control.UIAxes
        LFP2                            matlab.ui.control.UIAxes
        LFP3                            matlab.ui.control.UIAxes
        LFP4                            matlab.ui.control.UIAxes
        PSDPanel                        matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        lfpp1                           matlab.ui.control.UIAxes
        lfpp2                           matlab.ui.control.UIAxes
        lfpp3                           matlab.ui.control.UIAxes
        lfpp4                           matlab.ui.control.UIAxes
        Nyquist                         matlab.ui.control.StateButton
        LFPQCPanel                      matlab.ui.container.Panel
        Lamp1                           matlab.ui.control.Lamp
        Lamp2                           matlab.ui.control.Lamp
        Lamp3                           matlab.ui.control.Lamp
        Lamp4                           matlab.ui.control.Lamp
        EEGTab                          matlab.ui.container.Tab
        UniquevaluesPanel               matlab.ui.container.Panel
        EEG1UniqueValuesEditFieldLabel  matlab.ui.control.Label
        eegU1                           matlab.ui.control.EditField
        EEG2UVEditFieldLabel            matlab.ui.control.Label
        eegU2                           matlab.ui.control.EditField
        EEG3UVLabel                     matlab.ui.control.Label
        eegU3                           matlab.ui.control.EditField
        EEG4UVLabel                     matlab.ui.control.Label
        eegU4                           matlab.ui.control.EditField
        EEG5UVLabel                     matlab.ui.control.Label
        eegU5                           matlab.ui.control.EditField
        EEG6UVLabel                     matlab.ui.control.Label
        eegU6                           matlab.ui.control.EditField
        EEG7UVLabel                     matlab.ui.control.Label
        eegU7                           matlab.ui.control.EditField
        EEG8UVLabel                     matlab.ui.control.Label
        eegU8                           matlab.ui.control.EditField
        EEG9UVLabel                     matlab.ui.control.Label
        eegU9                           matlab.ui.control.EditField
        EEG10UVLabel                    matlab.ui.control.Label
        eegU10                          matlab.ui.control.EditField
        EEG11UVLabel                    matlab.ui.control.Label
        eegU11                          matlab.ui.control.EditField
        EEGplotsPanel                   matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        eeg1                            matlab.ui.control.UIAxes
        eeg2                            matlab.ui.control.UIAxes
        eeg3                            matlab.ui.control.UIAxes
        eeg4                            matlab.ui.control.UIAxes
        eeg5                            matlab.ui.control.UIAxes
        eeg6                            matlab.ui.control.UIAxes
        eeg7                            matlab.ui.control.UIAxes
        eeg8                            matlab.ui.control.UIAxes
        eeg9                            matlab.ui.control.UIAxes
        eeg10                           matlab.ui.control.UIAxes
        eeg11                           matlab.ui.control.UIAxes
        EEGQCPanel                      matlab.ui.container.Panel
        LampEEG1                        matlab.ui.control.Lamp
        LampEEG2                        matlab.ui.control.Lamp
        LampEEG3                        matlab.ui.control.Lamp
        LampEEG4                        matlab.ui.control.Lamp
        LampEEG5                        matlab.ui.control.Lamp
        LampEEG6                        matlab.ui.control.Lamp
        LampEEG7                        matlab.ui.control.Lamp
        LampEEG8                        matlab.ui.control.Lamp
        LampEEG9                        matlab.ui.control.Lamp
        LampEEG10                       matlab.ui.control.Lamp
        LampEEG11                       matlab.ui.control.Lamp
        ZoomInEEG                       matlab.ui.control.StateButton
        PSDEEG                          matlab.ui.control.StateButton
        EOGTab                          matlab.ui.container.Tab
        UniqueValuesPanel               matlab.ui.container.Panel
        UniqueValuesEOG1EditFieldLabel  matlab.ui.control.Label
        eogU1                           matlab.ui.control.NumericEditField
        UniqueValuesEOG2EditFieldLabel  matlab.ui.control.Label
        eogU2                           matlab.ui.control.NumericEditField
        EOGPanel                        matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        eog1                            matlab.ui.control.UIAxes
        eog2                            matlab.ui.control.UIAxes
        EOGQCPanel                      matlab.ui.container.Panel
        LampEOG1                        matlab.ui.control.Lamp
        LampEOG2                        matlab.ui.control.Lamp
        ZoomInEOG                       matlab.ui.control.StateButton
        PSDEOG                          matlab.ui.control.StateButton
        EMGTab                          matlab.ui.container.Tab
        UniqueValuesPanel_2             matlab.ui.container.Panel
        UniqueValuesEMG1EditFieldLabel  matlab.ui.control.Label
        emgU1                           matlab.ui.control.NumericEditField
        UniqueValuesEMG2EditFieldLabel  matlab.ui.control.Label
        emgU2                           matlab.ui.control.NumericEditField
        UniqueValuesEMG3EditFieldLabel  matlab.ui.control.Label
        emgU3                           matlab.ui.control.NumericEditField
        EMGPanel                        matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        emg1                            matlab.ui.control.UIAxes
        emg2                            matlab.ui.control.UIAxes
        emg3                            matlab.ui.control.UIAxes
        EMGQCPanel                      matlab.ui.container.Panel
        LampEMG1                        matlab.ui.control.Lamp
        LampEMG2                        matlab.ui.control.Lamp
        LampEMG3                        matlab.ui.control.Lamp
        ZoomInEMG                       matlab.ui.control.StateButton
        PSDEMG                          matlab.ui.control.StateButton
    end

    % REVISION HISTORY:
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    % Revision 02/18/2021 - 11:57AM
    % 1. Remove guassian distribution
    % 2. Add label for LFP data


    properties (Access = private)
        fileLOC % Description
        fileLIST
        eeglist
        labTab
        curCHAN
        allChan
        lfpPLOTceeg
        allLFP
        Usefile
        allTTsf
        allEEG
        allEOG
        allEMG
        allEEgTs
        allEOgTs
        allEMgTs
    end
    
    methods (Access = private)
        
        function plotDATA(app)
            
            % START UPDATE PROGRESS BAR
            prog = uiprogressdlg(app.UIFigure,"Title","QC LFP",...
                "Indeterminate","on","Message","Processing LFP");
            drawnow
            
            % UPDATE to SHOW 
            prog.Message = "Computing common reference";
            drawnow
            % Extract LFP IDs
            LFPdat = zeros(4,length(app.allLFP.c1));
            for chI = 1:numel(fieldnames(app.allLFP))
                chIi = chI - 1;
                LFPdat(chI,:) = app.allLFP.(['c',num2str(chIi)]);
            end
            % Compute mean
            LFPmean = mean(LFPdat);
            
            % Sutract common reference
            LFPref = LFPdat - LFPmean;
            
            % UPDATE to SHOW
            prog.Message = "Computing values of interest";
            drawnow
            
            % Loop through LFP
            for li = 1:4
                
                trange = abs(round(min(min(LFPref(li,:)))) - round(max(max(LFPref(li,:)))))*4;
                
                % Should be between 5-100
                tmpUv = round((numel(unique(LFPref(li,:)))/trange)*100,1);
                app.(['uLFP',num2str(li)]).Value = tmpUv;
                
                % Should be between 5-100
                tmpVar = round(std(LFPref(li,:)));
                app.(['vLFP',num2str(li)]).Value = tmpVar;
                
            end
            
            % UPDATE to SHOW
            prog.Message = "Computing QC values";
            drawnow
            % Plot over Guassian
            for lp = 1:4
                
                lfpn = normalize(LFPref(lp,:),'scale');
                
                lfpnRs = randsample(lfpn, round(length(lfpn)*0.1), false);

                histogram(app.(['LFP',num2str(lp)]),lfpnRs,'EdgeColor','none',...
                    'FaceAlpha',0.5,'FaceColor','k');
                
                
                legend(app.(['LFP',num2str(lp)]),['Channel' , num2str(lp)])
                
            end
            
            % Plot PSD
            prog.Message = "Creating tables";
            drawnow
            TT1f = timetable(transpose(double(app.allLFP.c0)),'SampleRate',1375,'VariableName',"DO");
            TT2f = timetable(transpose(double(app.allLFP.c1)),'SampleRate',1375,'VariableName',"D1");
            TT3f = timetable(transpose(double(app.allLFP.c2)),'SampleRate',1375,'VariableName',"D2");
            TT4f = timetable(transpose(double(app.allLFP.c3)),'SampleRate',1375,'VariableName',"D3");

            app.allTTsf = {TT1f,TT2f,TT3f,TT4f};
            

            for tti = 1:4
                
                [pxx,f] = pspectrum(app.allTTsf{tti},"FrequencyLimits",[0 50]);
                plot(app.(['lfpp',num2str(tti)]),f,pow2db(pxx),'LineWidth',2)
                xlabel(app.(['lfpp',num2str(tti)]),'Frequency (Hz)')
                ylabel(app.(['lfpp',num2str(tti)]),'PowerSpec (dB)')

            end


            app.Nyquist.Enable = 'on';
            
            %% For EEG panel
            for eegi = 1:11
                
                eegT = double(app.allEEG.(['eeg',num2str(eegi)]));
                
                trange = abs(round(min(min(eegT))) - round(max(max(eegT))));
                
                % Should be between 5-50
                tmpUv = round((numel(unique(eegT))/trange)*100,1);
                app.(['eegU',num2str(eegi)]).Value = num2str(tmpUv);
            end
            
            % EEG plot
            for eegi = 1:11
                plot(app.(['eeg',num2str(eegi)]),app.allEEG.(['eeg',num2str(eegi)]),'LineWidth',2)
            end
            
            app.allEEgTs = cell(1,11);
            for eegi = 1:11
                app.allEEgTs{eegi} = timetable(transpose(double(app.allEEG.(['eeg',num2str(eegi)]))),'SampleRate',1375,'VariableName',{['e',num2str(eegi)]});
            end

            
            %% For EOG panel
            for eogi = 1:2
                
                eogT = double(app.allEOG.(['eog',num2str(eogi)]));
                
                trange = abs(round(min(min(eogT))) - round(max(max(eogT))));
                
                % Should be between 5-50
                tmpUv = round((numel(unique(eogT))/trange)*100,1);
                app.(['eogU',num2str(eogi)]).Value = tmpUv;
            end
            
            % EOG plot
            for eogi = 1:2
                plot(app.(['eog',num2str(eogi)]),app.allEOG.(['eog',num2str(eogi)]),'LineWidth',2)
            end
            
            
            app.allEOgTs = cell(1,2);
            for eogi = 1:2
                app.allEOgTs{eogi} = timetable(transpose(double(app.allEOG.(['eog',num2str(eogi)]))),'SampleRate',1375,'VariableName',{['e',num2str(eogi)]});
            end

            
            %% For EMG panel
            for emgi = 1:3
                
                emgT = double(app.allEMG.(['emg',num2str(emgi)]));
                
                trange = abs(round(min(min(emgT))) - round(max(max(emgT))));
                
                % Should be between 5-50
                tmpUv = round((numel(unique(emgT))/trange)*100,1);
                app.(['emgU',num2str(emgi)]).Value = tmpUv;
            end
            
            % EMG plot
            for emgi = 1:3
                plot(app.(['emg',num2str(emgi)]),app.allEMG.(['emg',num2str(emgi)]),'LineWidth',2)
            end
            
            % Change for EMG
            app.allEMgTs = cell(1,3);
            for emgi = 1:3
                app.allEMgTs{emgi} = timetable(transpose(double(app.allEMG.(['emg',num2str(emgi)]))),'SampleRate',1375,'VariableName',{['e',num2str(emgi)]});
            end

            finalCHECK(app)
            
            % END PROGRESS BAR
            close(prog)
        end
        

        function finalCHECK(app)
            
            for li = 1:4
                
                if app.(['uLFP',num2str(li)]).Value > 1 &&...
                        app.(['uLFP',num2str(li)]).Value < 110
                    uniqVal = 1;
                else 
                    uniqVal = 0;
                end
                
                if app.(['vLFP',num2str(li)]).Value > 1 &&...
                        app.(['vLFP',num2str(li)]).Value < 200
                    varVal = 1;
                else
                    varVal = 0;
                end
                
                if app.recDur.Value > 2
                    durVal = 1;
                else
                    durVal = 0;
                end
                
                if uniqVal && varVal && durVal
                    app.(['Lamp',num2str(li)]).Color = 'g';
                else
                    app.(['Lamp',num2str(li)]).Color = 'r';
                end
                
            end
            
            
            for eegi = 1:11
                
                eegVt = str2double(app.(['eegU',num2str(eegi)]).Value);
                
                if eegVt > 30 && eegVt < 110
                    
                    app.(['LampEEG',num2str(eegi)]).Color = 'g';
                else
                    app.(['LampEEG',num2str(eegi)]).Color = 'r';
                end
                
            end
            
            for eogi = 1:2
                
                if app.(['eogU',num2str(eogi)]).Value > 40 &&...
                        app.(['eogU',num2str(eogi)]).Value < 110
                
                app.(['LampEOG',num2str(eogi)]).Color = 'g';
                else
                    app.(['LampEOG',num2str(eogi)]).Color = 'r';
                
                end
                
            end
            
            for emgi = 1:3
                
                if app.(['emgU',num2str(emgi)]).Value > 40 &&...
                        app.(['emgU',num2str(emgi)]).Value < 110
                
                app.(['LampEMG',num2str(emgi)]).Color = 'g';
                else
                app.(['LampEMG',num2str(emgi)]).Color = 'r';
                end
                
            end
             
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: LOAD
        function LOADMenuSelected(app, event)
            
            [app.Usefile,app.fileLOC] = uigetfile('*.mat');
          
            cd(app.fileLOC)
            
            % Combine first 3 files
            app.allChan = struct;
            curFile = app.Usefile;
            
            % LFP
            for ni = 1:4
                nii = ni - 1;
                
                load(curFile,['CDBS_',num2str(nii)]);
                dat1 = eval(['CDBS_',num2str(nii)]);
                app.allLFP.(['c',num2str(nii)]) = dat1.Samples;
                
            end
            
            % EEG
            eeGs = {'F3','Fz','F4','A1','C3','Cz','C4','A2','O1','Oz','O2'};
            for eegi = 1:11
                
                if length(num2str(eegi)) == 1
                    curName = ['CEEG_2___0',num2str(eegi),'___',eeGs{eegi}];
                    load(curFile,curName)
                else
                    curName = ['CEEG_2___',num2str(eegi),'___',eeGs{eegi}];
                    load(curFile,curName)
                end
                dat1 = eval(curName);
                app.allEEG.(['eeg',num2str(eegi)]) = dat1.Samples;
            end
            
           % EOG
            for eogi = 1:2
                curName = ['CEOG_',num2str(eogi)];
                load(curFile,curName)
                
                dat1 = eval(curName);
                app.allEOG.(['eog',num2str(eogi)]) = dat1.Samples; 
            end
            
            % EMG
            emGs = {'CChin_1','CChin_2','CChin_Z'};
            for emgi = 1:3
                curName = emGs{emgi};
                load(curFile,curName)
                
                dat1 = eval(curName);
                app.allEMG.(['emg',num2str(emgi)]) = dat1.Samples; 
            end
            
            % Extract recording information          
            load(curFile,['CDBS_',num2str(0)]);
            timeall = eval(['CDBS_',num2str(0)]);
            tbegint = timeall.TimeBegin;
            tendt = timeall.TimeEnd;

            tmpTime = tendt - tbegint;

            app.recDur.Value = (round(tmpTime)/60);
            
            % Update plots
            plotDATA(app)
            
            
        end

        % Value changed function: Nyquist
        function NyquistValueChanged(app, event)
            value = app.Nyquist.Value;
            
            if value
                for tti = 1:4
                    [pxx,f] = pspectrum(app.allTTsf{tti},"FrequencyLimits",[0 round(1375/2)]);
                    plot(app.(['lfpp',num2str(tti)]),f,pow2db(pxx),'LineWidth',2)
                    xlabel(app.(['lfpp',num2str(tti)]),'Frequency (Hz)')
                    ylabel(app.(['lfpp',num2str(tti)]),'PowerSpec (dB)')

                    
                end
            else
                for tti = 1:4
                    [pxx,f] = pspectrum(app.allTTsf{tti},"FrequencyLimits",[0 50]);
                    plot(app.(['lfpp',num2str(tti)]),f,pow2db(pxx),'LineWidth',2)
                    xlabel(app.(['lfpp',num2str(tti)]),'Frequency (Hz)')
                    ylabel(app.(['lfpp',num2str(tti)]),'PowerSpec (dB)')

                end
            end
            
        end

        % Value changed function: ZoomInEEG
        function ZoomInEEGValueChanged(app, event)
            value = app.ZoomInEEG.Value;
            
            if value
                app.PSDEEG.Enable = 'Off';
                
                % Select Q1 to Q3
                q1 = round(length(app.allEEG.eeg1) * 0.25);
                q3 = round(length(app.allEEG.eeg1) * 0.75);
                
                population = q1:q3;
                
                startPt = randsample(population,1);
                
                ptIndex = startPt:1:startPt+700;
                
                for eegi = 1:11
                    plot(app.(['eeg',num2str(eegi)]),app.allEEG.(['eeg',num2str(eegi)])(ptIndex),'LineWidth',2)
                    xlabel(app.(['eeg',num2str(eegi)]),'samples')
                    ylabel(app.(['eeg',num2str(eegi)]),'mV')
                end
                
                
            else
                app.PSDEEG.Enable = 'On';
                for eegi = 1:11
                    plot(app.(['eeg',num2str(eegi)]),app.allEEG.(['eeg',num2str(eegi)]),'LineWidth',2)
                end
                
            end
            
        end

        % Value changed function: PSDEEG
        function PSDEEGValueChanged(app, event)
            value = app.PSDEEG.Value;
            if value
                app.ZoomInEEG.Enable = 'Off';
                % START UPDATE PROGRESS BAR
                prog = uiprogressdlg(app.UIFigure,"Title","QC LFP",...
                    "Indeterminate","on","Message","EEG PSD");
                drawnow
                for eegi = 1:11
                    [pxx,f] = pspectrum(app.allEEgTs{eegi},"FrequencyLimits",[0 35]);
                    plot(app.(['eeg',num2str(eegi)]),f,pow2db(pxx),'LineWidth',2)
                    xlabel(app.(['eeg',num2str(eegi)]),'Hz')
                    ylabel(app.(['eeg',num2str(eegi)]),'dB')
                end
                close(prog)
            else
                app.ZoomInEEG.Enable = 'On';
                for eegi = 1:11
                    plot(app.(['eeg',num2str(eegi)]),app.allEEG.(['eeg',num2str(eegi)]),'LineWidth',2)
                    xlabel(app.(['eeg',num2str(eegi)]),'samples')
                    ylabel(app.(['eeg',num2str(eegi)]),'mV')
                end
                
            end
        end

        % Value changed function: ZoomInEOG
        function ZoomInEOGValueChanged(app, event)
            value = app.ZoomInEOG.Value;
            if value
                app.PSDEOG.Enable = 'Off';
                
                % Select Q1 to Q3
                q1 = round(length(app.allEOG.eog1) * 0.25);
                q3 = round(length(app.allEOG.eog1) * 0.75);
                
                population = q1:q3;
                
                startPt = randsample(population,1);
                
                ptIndex = startPt:1:startPt+700;
                
                for eogi = 1:2
                    plot(app.(['eog',num2str(eogi)]),app.allEOG.(['eog',num2str(eogi)])(ptIndex),'LineWidth',2)
                    xlabel(app.(['eog',num2str(eogi)]),'samples')
                    ylabel(app.(['eog',num2str(eogi)]),'mV')
                end
                
                
            else
                app.PSDEOG.Enable = 'On';
                for eogi = 1:2
                    plot(app.(['eog',num2str(eogi)]),app.allEOG.(['eog',num2str(eogi)]),'LineWidth',2)
                end
                
            end
        end

        % Value changed function: PSDEOG
        function PSDEOGValueChanged(app, event)
            value = app.PSDEOG.Value;
            if value
                app.ZoomInEOG.Enable = 'Off';
                for eogi = 1:2
                    [pxx,f] = pspectrum(app.allEOgTs{eogi},"FrequencyLimits",[0 35]);
                    plot(app.(['eog',num2str(eogi)]),f,pow2db(pxx),'LineWidth',2)
                    xlabel(app.(['eog',num2str(eogi)]),'Frequency (Hz)')
                    ylabel(app.(['eog',num2str(eogi)]),'PS (dB)')
                end
                
            else
                app.ZoomInEOG.Enable = 'On';
                for eogi = 1:2
                    plot(app.(['eog',num2str(eogi)]),app.allEOG.(['eog',num2str(eogi)]),'LineWidth',2)
                    xlabel(app.(['eog',num2str(eogi)]),'samples')
                    ylabel(app.(['eog',num2str(eogi)]),'mV')
                end
                
            end
        end

        % Value changed function: ZoomInEMG
        function ZoomInEMGValueChanged(app, event)
            value = app.ZoomInEMG.Value;
            if value
                app.PSDEMG.Enable = 'Off';
                
                % Select Q1 to Q3
                q1 = round(length(app.allEMG.emg1) * 0.25);
                q3 = round(length(app.allEMG.emg1) * 0.75);
                
                population = q1:q3;
                
                startPt = randsample(population,1);
                
                ptIndex = startPt:1:startPt+700;
                
                for emgi = 1:3
                    plot(app.(['emg',num2str(emgi)]),app.allEMG.(['emg',num2str(emgi)])(ptIndex),'LineWidth',2)
                    xlabel(app.(['emg',num2str(emgi)]),'samples')
                    ylabel(app.(['emg',num2str(emgi)]),'mV')
                end
            else
                app.PSDEMG.Enable = 'On';
                for emgi = 1:3
                    plot(app.(['emg',num2str(emgi)]),app.allEMG.(['emg',num2str(emgi)]),'LineWidth',2)
                    xlabel(app.(['emg',num2str(emgi)]),'Hz')
                    ylabel(app.(['emg',num2str(emgi)]),'dB')
                end
                
            end
        end

        % Value changed function: PSDEMG
        function PSDEMGValueChanged(app, event)
            value = app.PSDEMG.Value;
            if value
                app.ZoomInEMG.Enable = 'Off';
                for emgi = 1:3
                    [pxx,f] = pspectrum(app.allEMgTs{emgi},"FrequencyLimits",[0 35]);
                    plot(app.(['emg',num2str(emgi)]),f,pow2db(pxx),'LineWidth',2)
                    xlabel(app.(['emg',num2str(emgi)]),'Frequency (Hz)')
                    ylabel(app.(['emg',num2str(emgi)]),'PS (dB)')
                end
                
            else
                app.ZoomInEMG.Enable = 'On';
                for emgi = 1:3
                    plot(app.(['emg',num2str(emgi)]),app.allEMG.(['emg',num2str(emgi)]),'LineWidth',2)
                    xlabel(app.(['emg',num2str(emgi)]),'samples')
                    ylabel(app.(['emg',num2str(emgi)]),'mV')
                end
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LOAD = uimenu(app.UIFigure);
            app.LOAD.MenuSelectedFcn = createCallbackFcn(app, @LOADMenuSelected, true);
            app.LOAD.Text = 'LOAD';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LFPTab = uitab(app.TabGroup);
            app.LFPTab.Title = 'LFP';
            app.MinutesofRecEditFieldLabel = uilabel(app.LFPTab);
            app.MinutesofRecEditFieldLabel.Text = 'Minutes of Rec. = ';
            app.recDur = uieditfield(app.LFPTab, 'numeric');
            app.recDur.Editable = 'off';
            app.SDofsignalLabel = uilabel(app.LFPTab);
            app.SDofsignalLabel.Text = 'SD of signal';
            app.vLFP4 = uieditfield(app.LFPTab, 'numeric');
            app.UniqueValuesLabel = uilabel(app.LFPTab);
            app.UniqueValuesLabel.Text = '% Unique Values';
            app.uLFP4 = uieditfield(app.LFPTab, 'numeric');
            app.UniqueValuesLabel_2 = uilabel(app.LFPTab);
            app.UniqueValuesLabel_2.Text = '% Unique Values';
            app.uLFP3 = uieditfield(app.LFPTab, 'numeric');
            app.SDofsignalLabel_2 = uilabel(app.LFPTab);
            app.SDofsignalLabel_2.Text = 'SD of signal';
            app.vLFP3 = uieditfield(app.LFPTab, 'numeric');
            app.UniqueValuesLabel_3 = uilabel(app.LFPTab);
            app.UniqueValuesLabel_3.Text = '% Unique Values';
            app.uLFP2 = uieditfield(app.LFPTab, 'numeric');
            app.SDofsignalLabel_3 = uilabel(app.LFPTab);
            app.SDofsignalLabel_3.Text = 'SD of signal';
            app.vLFP2 = uieditfield(app.LFPTab, 'numeric');
            app.SDofsignalREF2500Label = uilabel(app.LFPTab);
            app.SDofsignalREF2500Label.Text = {'SD of signal'; 'REF: 2-500'};
            app.vLFP1 = uieditfield(app.LFPTab, 'numeric');
            app.UniqueValuesREF5200Label = uilabel(app.LFPTab);
            app.UniqueValuesREF5200Label.Text = {'% Unique Values'; 'REF: 5-200'};
            app.uLFP1 = uieditfield(app.LFPTab, 'numeric');
            app.LFPPanel = uipanel(app.LFPTab);
            app.LFPPanel.Title = 'LFP';
            app.GridLayout = uigridlayout(app.LFPPanel);
            app.LFP1 = uiaxes(app.GridLayout);
            xlabel(app.LFP1, 'Scaled values')
            ylabel(app.LFP1, 'Count')
            zlabel(app.LFP1, 'Z')
            app.LFP2 = uiaxes(app.GridLayout);
            xlabel(app.LFP2, 'Scaled values')
            ylabel(app.LFP2, 'Count')
            zlabel(app.LFP2, 'Z')
            app.LFP3 = uiaxes(app.GridLayout);
            xlabel(app.LFP3, 'Scaled values')
            ylabel(app.LFP3, 'Count')
            zlabel(app.LFP3, 'Z')
            app.LFP4 = uiaxes(app.GridLayout);
            xlabel(app.LFP4, 'Scaled values')
            ylabel(app.LFP4, 'Count')
            zlabel(app.LFP4, 'Z')
            app.PSDPanel = uipanel(app.LFPTab);
            app.PSDPanel.Title = 'PSD';
            app.GridLayout2 = uigridlayout(app.PSDPanel);
            app.lfpp1 = uiaxes(app.GridLayout2);
            app.lfpp2 = uiaxes(app.GridLayout2);
            app.lfpp3 = uiaxes(app.GridLayout2);
            app.lfpp4 = uiaxes(app.GridLayout2);
            app.Nyquist = uibutton(app.LFPTab, 'state');
            app.Nyquist.ValueChangedFcn = createCallbackFcn(app, @NyquistValueChanged, true);
            app.Nyquist.Enable = 'off';
            app.Nyquist.Text = 'Nyquist';
            app.LFPQCPanel = uipanel(app.LFPTab);
            app.LFPQCPanel.Title = 'LFP QC';
            app.Lamp1 = uilamp(app.LFPQCPanel);
            app.Lamp2 = uilamp(app.LFPQCPanel);
            app.Lamp3 = uilamp(app.LFPQCPanel);
            app.Lamp4 = uilamp(app.LFPQCPanel);
            app.EEGTab = uitab(app.TabGroup);
            app.EEGTab.Title = 'EEG';
            app.UniquevaluesPanel = uipanel(app.EEGTab);
            app.UniquevaluesPanel.Title = 'Unique values';
            app.EEG1UniqueValuesEditFieldLabel = uilabel(app.UniquevaluesPanel);
            app.EEG1UniqueValuesEditFieldLabel.Text = 'EEG1 Unique Values';
            app.eegU1 = uieditfield(app.UniquevaluesPanel, 'text');
            app.EEG2UVEditFieldLabel = uilabel(app.UniquevaluesPanel);
            app.EEG2UVEditFieldLabel.Text = 'EEG2 UV %';
            app.eegU2 = uieditfield(app.UniquevaluesPanel, 'text');
            app.EEG3UVLabel = uilabel(app.UniquevaluesPanel);
            app.EEG3UVLabel.Text = 'EEG3 UV %';
            app.eegU3 = uieditfield(app.UniquevaluesPanel, 'text');
            app.EEG4UVLabel = uilabel(app.UniquevaluesPanel);
            app.EEG4UVLabel.Text = 'EEG4 UV %';
            app.eegU4 = uieditfield(app.UniquevaluesPanel, 'text');
            app.EEG5UVLabel = uilabel(app.UniquevaluesPanel);
            app.EEG5UVLabel.Text = 'EEG5 UV %';
            app.eegU5 = uieditfield(app.UniquevaluesPanel, 'text');
            app.EEG6UVLabel = uilabel(app.UniquevaluesPanel);
            app.EEG6UVLabel.Text = 'EEG6 UV %';
            app.eegU6 = uieditfield(app.UniquevaluesPanel, 'text');
            app.EEG7UVLabel = uilabel(app.UniquevaluesPanel);
            app.EEG7UVLabel.Text = 'EEG7 UV %';
            app.eegU7 = uieditfield(app.UniquevaluesPanel, 'text');
            app.EEG8UVLabel = uilabel(app.UniquevaluesPanel);
            app.EEG8UVLabel.Text = 'EEG8 UV %';
            app.eegU8 = uieditfield(app.UniquevaluesPanel, 'text');
            app.EEG9UVLabel = uilabel(app.UniquevaluesPanel);
            app.EEG9UVLabel.Text = 'EEG9 UV %';
            app.eegU9 = uieditfield(app.UniquevaluesPanel, 'text');
            app.EEG10UVLabel = uilabel(app.UniquevaluesPanel);
            app.EEG10UVLabel.Text = 'EEG10 UV %';
            app.eegU10 = uieditfield(app.UniquevaluesPanel, 'text');
            app.EEG11UVLabel = uilabel(app.UniquevaluesPanel);
            app.EEG11UVLabel.Text = 'EEG11 UV %';
            app.eegU11 = uieditfield(app.UniquevaluesPanel, 'text');
            app.EEGplotsPanel = uipanel(app.EEGTab);
            app.EEGplotsPanel.Title = 'EEG plots';
            app.GridLayout3 = uigridlayout(app.EEGplotsPanel);
            app.eeg1 = uiaxes(app.GridLayout3);
            zlabel(app.eeg1, 'Z')
            app.eeg1.XTick = [];
            app.eeg1.YTick = [];
            app.eeg2 = uiaxes(app.GridLayout3);
            zlabel(app.eeg2, 'Z')
            app.eeg2.XTick = [];
            app.eeg2.YTick = [];
            app.eeg3 = uiaxes(app.GridLayout3);
            zlabel(app.eeg3, 'Z')
            app.eeg3.XTick = [];
            app.eeg3.YTick = [];
            app.eeg4 = uiaxes(app.GridLayout3);
            zlabel(app.eeg4, 'Z')
            app.eeg4.XTick = [];
            app.eeg4.YTick = [];
            app.eeg5 = uiaxes(app.GridLayout3);
            zlabel(app.eeg5, 'Z')
            app.eeg5.XTick = [];
            app.eeg5.YTick = [];
            app.eeg6 = uiaxes(app.GridLayout3);
            zlabel(app.eeg6, 'Z')
            app.eeg6.XTick = [];
            app.eeg6.YTick = [];
            app.eeg7 = uiaxes(app.GridLayout3);
            zlabel(app.eeg7, 'Z')
            app.eeg7.XTick = [];
            app.eeg7.XTickLabel = '';
            app.eeg7.YTick = [];
            app.eeg8 = uiaxes(app.GridLayout3);
            zlabel(app.eeg8, 'Z')
            app.eeg8.XTick = [];
            app.eeg8.YTick = [];
            app.eeg9 = uiaxes(app.GridLayout3);
            zlabel(app.eeg9, 'Z')
            app.eeg9.XTick = [];
            app.eeg9.YTick = [];
            app.eeg10 = uiaxes(app.GridLayout3);
            zlabel(app.eeg10, 'Z')
            app.eeg10.XTick = [];
            app.eeg10.YTick = [];
            app.eeg11 = uiaxes(app.GridLayout3);
            zlabel(app.eeg11, 'Z')
            app.eeg11.XTick = [];
            app.eeg11.YTick = [];
            app.EEGQCPanel = uipanel(app.EEGTab);
            app.EEGQCPanel.Title = 'EEG QC';
            app.LampEEG1 = uilamp(app.EEGQCPanel);
            app.LampEEG2 = uilamp(app.EEGQCPanel);
            app.LampEEG3 = uilamp(app.EEGQCPanel);
            app.LampEEG4 = uilamp(app.EEGQCPanel);
            app.LampEEG5 = uilamp(app.EEGQCPanel);
            app.LampEEG6 = uilamp(app.EEGQCPanel);
            app.LampEEG7 = uilamp(app.EEGQCPanel);
            app.LampEEG8 = uilamp(app.EEGQCPanel);
            app.LampEEG9 = uilamp(app.EEGQCPanel);
            app.LampEEG10 = uilamp(app.EEGQCPanel);
            app.LampEEG11 = uilamp(app.EEGQCPanel);
            app.ZoomInEEG = uibutton(app.EEGTab, 'state');
            app.ZoomInEEG.ValueChangedFcn = createCallbackFcn(app, @ZoomInEEGValueChanged, true);
            app.ZoomInEEG.Text = 'Zoom In';
            app.PSDEEG = uibutton(app.EEGTab, 'state');
            app.PSDEEG.ValueChangedFcn = createCallbackFcn(app, @PSDEEGValueChanged, true);
            app.PSDEEG.Text = 'PSD';
            app.EOGTab = uitab(app.TabGroup);
            app.EOGTab.Title = 'EOG';
            app.UniqueValuesPanel = uipanel(app.EOGTab);
            app.UniqueValuesPanel.Title = 'Unique Values';
            app.UniqueValuesEOG1EditFieldLabel = uilabel(app.UniqueValuesPanel);
            app.UniqueValuesEOG1EditFieldLabel.Text = 'Unique Values EOG1';
            app.eogU1 = uieditfield(app.UniqueValuesPanel, 'numeric');
            app.UniqueValuesEOG2EditFieldLabel = uilabel(app.UniqueValuesPanel);
            app.UniqueValuesEOG2EditFieldLabel.Text = 'Unique Values EOG2';
            app.eogU2 = uieditfield(app.UniqueValuesPanel, 'numeric');
            app.EOGPanel = uipanel(app.EOGTab);
            app.EOGPanel.Title = 'EOG';
            app.GridLayout4 = uigridlayout(app.EOGPanel);
            app.eog1 = uiaxes(app.GridLayout4);
            zlabel(app.eog1, 'Z')
            app.eog1.XTick = [];
            app.eog1.YTick = [];
            app.eog2 = uiaxes(app.GridLayout4);
            zlabel(app.eog2, 'Z')
            app.eog2.XTick = [];
            app.eog2.YTick = [];
            app.EOGQCPanel = uipanel(app.EOGTab);
            app.EOGQCPanel.Title = 'EOG QC';
            app.LampEOG1 = uilamp(app.EOGQCPanel);
            app.LampEOG2 = uilamp(app.EOGQCPanel);
            app.ZoomInEOG = uibutton(app.EOGTab, 'state');
            app.ZoomInEOG.ValueChangedFcn = createCallbackFcn(app, @ZoomInEOGValueChanged, true);
            app.ZoomInEOG.Text = 'Zoom In';
            app.PSDEOG = uibutton(app.EOGTab, 'state');
            app.PSDEOG.ValueChangedFcn = createCallbackFcn(app, @PSDEOGValueChanged, true);
            app.PSDEOG.Text = 'PSD';
            app.EMGTab = uitab(app.TabGroup);
            app.EMGTab.Title = 'EMG';
            app.UniqueValuesPanel_2 = uipanel(app.EMGTab);
            app.UniqueValuesPanel_2.Title = 'Unique Values';
            app.UniqueValuesEMG1EditFieldLabel = uilabel(app.UniqueValuesPanel_2);
            app.UniqueValuesEMG1EditFieldLabel.Text = 'Unique Values EMG1';
            app.emgU1 = uieditfield(app.UniqueValuesPanel_2, 'numeric');
            app.UniqueValuesEMG2EditFieldLabel = uilabel(app.UniqueValuesPanel_2);
            app.UniqueValuesEMG2EditFieldLabel.Text = 'Unique Values EMG2';
            app.emgU2 = uieditfield(app.UniqueValuesPanel_2, 'numeric');
            app.UniqueValuesEMG3EditFieldLabel = uilabel(app.UniqueValuesPanel_2);
            app.UniqueValuesEMG3EditFieldLabel.Text = 'Unique Values EMG3';
            app.emgU3 = uieditfield(app.UniqueValuesPanel_2, 'numeric');
            app.EMGPanel = uipanel(app.EMGTab);
            app.EMGPanel.Title = 'EMG';
            app.GridLayout5 = uigridlayout(app.EMGPanel);
            app.emg1 = uiaxes(app.GridLayout5);
            zlabel(app.emg1, 'Z')
            app.emg1.XTick = [];
            app.emg1.YTick = [];
            app.emg2 = uiaxes(app.GridLayout5);
            zlabel(app.emg2, 'Z')
            app.emg2.XTick = [];
            app.emg2.YTick = [];
            app.emg3 = uiaxes(app.GridLayout5);
            zlabel(app.emg3, 'Z')
            app.emg3.XTick = [];
            app.emg3.YTick = [];
            app.EMGQCPanel = uipanel(app.EMGTab);
            app.EMGQCPanel.Title = 'EMG QC';
            app.LampEMG1 = uilamp(app.EMGQCPanel);
            app.LampEMG2 = uilamp(app.EMGQCPanel);
            app.LampEMG3 = uilamp(app.EMGQCPanel);
            app.ZoomInEMG = uibutton(app.EMGTab, 'state');
            app.ZoomInEMG.ValueChangedFcn = createCallbackFcn(app, @ZoomInEMGValueChanged, true);
            app.ZoomInEMG.Text = 'Zoom In';
            app.PSDEMG = uibutton(app.EMGTab, 'state');
            app.PSDEMG.ValueChangedFcn = createCallbackFcn(app, @PSDEMGValueChanged, true);
            app.PSDEMG.Text = 'PSD';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignalQC_v002_Tabs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end